classdef VisioGuard_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ventana1                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        VentanaInicio_Tab              matlab.ui.container.Tab
        AcercaDe_Button                matlab.ui.control.Button
        CE_Image                       matlab.ui.control.Image
        GlaucoScan_Label               matlab.ui.control.Label
        Fecha_Label                    matlab.ui.control.Label
        Usuario_Image                  matlab.ui.control.Image
        Nombre_Label                   matlab.ui.control.Label
        Apellidos_Label                matlab.ui.control.Label
        ErrorInicio_Label              matlab.ui.control.Label
        Nombre_EditField               matlab.ui.control.EditField
        Apellidos_EditField            matlab.ui.control.EditField
        Continuar_Button               matlab.ui.control.Button
        VisioGuard_Image               matlab.ui.control.Image
        MU_Image                       matlab.ui.control.Image
        Marco_Image                    matlab.ui.control.Image
        Tab2                           matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        GlaucoScan2_Label              matlab.ui.control.Label
        Diagnostico_Label              matlab.ui.control.Label
        Segmentacion_Label             matlab.ui.control.Label
        Calidad_Label                  matlab.ui.control.Label
        PropiedadesdeImagen_Label      matlab.ui.control.Label
        ErrorInsertar_Label            matlab.ui.control.Label
        NuevoPaciente_Button           matlab.ui.control.Button
        Nombreyapellido_Label          matlab.ui.control.Label
        Inserteimagen_Button           matlab.ui.control.Button
        EyeFundus_Image                matlab.ui.control.Image
        VisioGuard2_Image              matlab.ui.control.Image
        MU2_Image                      matlab.ui.control.Image
        Marco2_Image                   matlab.ui.control.Image
        AcercaDe_Tab                   matlab.ui.container.Tab
        AcercaDe2_Label                matlab.ui.control.Label
        Etiqueta_Image_2               matlab.ui.control.Button
        Etiqueta_Image                 matlab.ui.control.Button
        SobreNosotros_Image            matlab.ui.control.Button
        CE2_Image                      matlab.ui.control.Image
        Inicio_Button                  matlab.ui.control.Button
        GlaucoScan_Label_2             matlab.ui.control.Label
        VisioGuard3_Image              matlab.ui.control.Image
        MU3_Image                      matlab.ui.control.Image
        Marco3_Image                   matlab.ui.control.Image
        Instrucciones_de_uso_Tab       matlab.ui.container.Tab
        Atras3_Button                  matlab.ui.control.Button
        Instrucciones_de_uso_Label     matlab.ui.control.Label
        CE3_Image                      matlab.ui.control.Image
        Inicio3_Button                 matlab.ui.control.Button
        GlaucoScan3_Label              matlab.ui.control.Label
        VisioGuard3_Image2             matlab.ui.control.Image
        MU3_Image_2                    matlab.ui.control.Image
        Marco3_Image_2                 matlab.ui.control.Image
        Etiqueta_Tab                   matlab.ui.container.Tab
        Atras4_Button_3                matlab.ui.control.Button
        Etiqueta_Label                 matlab.ui.control.Label
        CE3_Image_2                    matlab.ui.control.Image
        Inicio3_Button_2               matlab.ui.control.Button
        GlaucoScan3_Label_2            matlab.ui.control.Label
        VisioGuard3_Image2_2           matlab.ui.control.Image
        MU3_Image_3                    matlab.ui.control.Image
        Marco3_Image_3                 matlab.ui.control.Image
        Sobre_nosotros_Tab             matlab.ui.container.Tab
        Sobre_nosotros_Label_2         matlab.ui.control.Label
        Slogan_Label                   matlab.ui.control.Label
        Atras4_Button                  matlab.ui.control.Button
        FotoEquipo_Image               matlab.ui.control.Image
        Texto_sobre_nosotros_TextArea  matlab.ui.control.TextArea
        Sobre_nosotros_Label           matlab.ui.control.Label
        CE4_Image                      matlab.ui.control.Image
        Inicio4_Button                 matlab.ui.control.Button
        GlaucoScan4_Label              matlab.ui.control.Label
        VisioGuard4_Image              matlab.ui.control.Image
        MU4_Image                      matlab.ui.control.Image
        Marco4_Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Continuar_Button
        function Continuar_ButtonPushed(app, event)
            if (isempty(app.Nombre_EditField.Value) || isempty(app.Apellidos_EditField.Value))
                app.ErrorInicio_Label.Visible = 'on'; %si alguna casilla vacia que salte el mensaje
            else
                app.ErrorInicio_Label.Visible = 'off';%si esta todo marcado que no aparezca
            end
            if(~isempty(app.Nombre_EditField.Value) && ~isempty(app.Apellidos_EditField.Value))
                app.TabGroup.SelectedTab = app.Tab2;  
            end
        end

        % Button pushed function: Inserteimagen_Button
        function Inserteimagen_ButtonPushed(app, event)
            [file, location] = uigetfile({'*.jpg';'*.png';}, 'Seleccione una imagen');
            if file ~= 0
                app.ErrorInsertar_Label.Visible='off';

                % Ubicacion de la imagen
                full_location = fullfile(location, file);
                % Mostrar la imagen en "Eye Fundus"
                app.EyeFundus_Image.ImageSource = full_location;
                %Mostrar la imagen y nombre paciente y esconder el boton para introducir la imagen
                app.EyeFundus_Image.Visible='on';
                app.Inserteimagen_Button.Visible='off';
                app.NuevoPaciente_Button.Visible='on';
                
                %Nombre y apellidos paciente
                app.Nombreyapellido_Label.Text=['Retinografía de ', app.Nombre_EditField.Value, ' ', app.Apellidos_EditField.Value];
                app.Nombreyapellido_Label.Visible='on';

                %Segmen, propiedades, calidad y diagnostico
                app.PropiedadesdeImagen_Label.Visible='on';
                app.Segmentacion_Label.Visible='on';
                app.Calidad_Label.Visible='on';
                app.Diagnostico_Label.Visible='on';
            else
                app.ErrorInsertar_Label.Visible='on';
            end
        end

        % Button pushed function: NuevoPaciente_Button
        function NuevoPaciente_ButtonPushed(app, event)
            %Nuevo paciente (volver pagina principal)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
            %Imagen, labels y botones del tab2 de 0
            app.Nombre_EditField.Value='';
            app.Apellidos_EditField.Value='';
            app.EyeFundus_Image.Visible='off';
            app.Inserteimagen_Button.Visible='on';
            app.NuevoPaciente_Button.Visible='off';
            app.Nombreyapellido_Label.Visible='off';
        end

        % Button pushed function: AcercaDe_Button
        function AcercaDe_ButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Button pushed function: Inicio_Button
        function Inicio_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Etiqueta_Image_2
        function Etiqueta_Image_2ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Instrucciones_de_uso_Tab;
        end

        % Button pushed function: Inicio3_Button
        function Inicio3_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Inicio3_Button_2
        function Inicio3_Button_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Inicio4_Button
        function Inicio4_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Etiqueta_Image
        function Etiqueta_ImageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Etiqueta_Tab;
        end

        % Button pushed function: SobreNosotros_Image
        function SobreNosotros_ImageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Sobre_nosotros_Tab;
        end

        % Button pushed function: Atras4_Button
        function Atras4_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Button pushed function: Atras3_Button
        function Atras3_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Button pushed function: Atras4_Button_3
        function Atras4_Button_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ventana1 = uifigure('Visible', 'off');
            app.ventana1.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.ventana1);
            app.VentanaInicio_Tab = uitab(app.TabGroup);
            app.VentanaInicio_Tab.Title = 'Ventana_inicio';
            app.Marco_Image = uiimage(app.VentanaInicio_Tab);
            app.Marco_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU_Image = uiimage(app.VentanaInicio_Tab);
            app.MU_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard_Image = uiimage(app.VentanaInicio_Tab);
            app.VisioGuard_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.Continuar_Button = uibutton(app.VentanaInicio_Tab, 'push');
            app.Continuar_Button.ButtonPushedFcn = createCallbackFcn(app, @Continuar_ButtonPushed, true);
            app.Continuar_Button.Text = 'Continuar';
            app.Apellidos_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.Nombre_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.ErrorInicio_Label = uilabel(app.VentanaInicio_Tab);
            app.ErrorInicio_Label.Visible = 'off';
            app.ErrorInicio_Label.Text = 'Asegurese de introducir su nombre y apellido';
            app.Apellidos_Label = uilabel(app.VentanaInicio_Tab);
            app.Apellidos_Label.Text = 'Apellidos';
            app.Nombre_Label = uilabel(app.VentanaInicio_Tab);
            app.Nombre_Label.Text = 'Nombre';
            app.Usuario_Image = uiimage(app.VentanaInicio_Tab);
            app.Usuario_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'user.png');
            app.Fecha_Label = uilabel(app.VentanaInicio_Tab);
            app.GlaucoScan_Label = uilabel(app.VentanaInicio_Tab);
            app.GlaucoScan_Label.Text = 'GlaucoScan';
            app.CE_Image = uiimage(app.VentanaInicio_Tab);
            app.CE_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.AcercaDe_Button = uibutton(app.VentanaInicio_Tab, 'push');
            app.AcercaDe_Button.ButtonPushedFcn = createCallbackFcn(app, @AcercaDe_ButtonPushed, true);
            app.AcercaDe_Button.Text = 'Acerca de';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Marco2_Image = uiimage(app.Tab2);
            app.Marco2_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU2_Image = uiimage(app.Tab2);
            app.MU2_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard2_Image = uiimage(app.Tab2);
            app.VisioGuard2_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.EyeFundus_Image = uiimage(app.Tab2);
            app.EyeFundus_Image.Visible = 'off';
            app.Inserteimagen_Button = uibutton(app.Tab2, 'push');
            app.Inserteimagen_Button.ButtonPushedFcn = createCallbackFcn(app, @Inserteimagen_ButtonPushed, true);
            app.Inserteimagen_Button.Text = 'Inserte imágen';
            app.Nombreyapellido_Label = uilabel(app.Tab2);
            app.Nombreyapellido_Label.Visible = 'off';
            app.Nombreyapellido_Label.Text = 'Nombre y apellido';
            app.NuevoPaciente_Button = uibutton(app.Tab2, 'push');
            app.NuevoPaciente_Button.ButtonPushedFcn = createCallbackFcn(app, @NuevoPaciente_ButtonPushed, true);
            app.NuevoPaciente_Button.Visible = 'off';
            app.NuevoPaciente_Button.Text = 'Nuevo Paciente';
            app.ErrorInsertar_Label = uilabel(app.Tab2);
            app.ErrorInsertar_Label.Visible = 'off';
            app.ErrorInsertar_Label.Text = 'Ha ocurrido un error. Intente insertar otra imágen.';
            app.PropiedadesdeImagen_Label = uilabel(app.Tab2);
            app.PropiedadesdeImagen_Label.Visible = 'off';
            app.PropiedadesdeImagen_Label.Text = 'Propiedades de Imágen';
            app.Calidad_Label = uilabel(app.Tab2);
            app.Calidad_Label.Visible = 'off';
            app.Calidad_Label.Text = 'Calidad :';
            app.Segmentacion_Label = uilabel(app.Tab2);
            app.Segmentacion_Label.Visible = 'off';
            app.Segmentacion_Label.Text = 'Segmentación';
            app.Diagnostico_Label = uilabel(app.Tab2);
            app.Diagnostico_Label.Visible = 'off';
            app.Diagnostico_Label.Text = 'Diagnostico :';
            app.GlaucoScan2_Label = uilabel(app.Tab2);
            app.GlaucoScan2_Label.Text = 'GlaucoScan';
            app.Image = uiimage(app.Tab2);
            app.Image.Visible = 'off';
            app.AcercaDe_Tab = uitab(app.TabGroup);
            app.AcercaDe_Tab.Title = 'AcercaDe_Tab';
            app.Marco3_Image = uiimage(app.AcercaDe_Tab);
            app.Marco3_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU3_Image = uiimage(app.AcercaDe_Tab);
            app.MU3_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard3_Image = uiimage(app.AcercaDe_Tab);
            app.VisioGuard3_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan_Label_2 = uilabel(app.AcercaDe_Tab);
            app.GlaucoScan_Label_2.Text = 'GlaucoScan';
            app.Inicio_Button = uibutton(app.AcercaDe_Tab, 'push');
            app.Inicio_Button.ButtonPushedFcn = createCallbackFcn(app, @Inicio_ButtonPushed, true);
            app.Inicio_Button.Text = 'Inicio';
            app.CE2_Image = uiimage(app.AcercaDe_Tab);
            app.CE2_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.SobreNosotros_Image = uibutton(app.AcercaDe_Tab, 'push');
            app.SobreNosotros_Image.ButtonPushedFcn = createCallbackFcn(app, @SobreNosotros_ImageButtonPushed, true);
            app.SobreNosotros_Image.Text = 'Sobre nosotros';
            app.Etiqueta_Image = uibutton(app.AcercaDe_Tab, 'push');
            app.Etiqueta_Image.ButtonPushedFcn = createCallbackFcn(app, @Etiqueta_ImageButtonPushed, true);
            app.Etiqueta_Image.Text = 'Etiqueta';
            app.Etiqueta_Image_2 = uibutton(app.AcercaDe_Tab, 'push');
            app.Etiqueta_Image_2.ButtonPushedFcn = createCallbackFcn(app, @Etiqueta_Image_2ButtonPushed, true);
            app.Etiqueta_Image_2.Text = 'Instrucciones de uso';
            app.AcercaDe2_Label = uilabel(app.AcercaDe_Tab);
            app.AcercaDe2_Label.Text = 'Acerca de';
            app.Instrucciones_de_uso_Tab = uitab(app.TabGroup);
            app.Instrucciones_de_uso_Tab.Title = 'Instrucciones_de_uso';
            app.Marco3_Image_2 = uiimage(app.Instrucciones_de_uso_Tab);
            app.Marco3_Image_2.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU3_Image_2 = uiimage(app.Instrucciones_de_uso_Tab);
            app.MU3_Image_2.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard3_Image2 = uiimage(app.Instrucciones_de_uso_Tab);
            app.VisioGuard3_Image2.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan3_Label = uilabel(app.Instrucciones_de_uso_Tab);
            app.GlaucoScan3_Label.Text = 'GlaucoScan';
            app.Inicio3_Button = uibutton(app.Instrucciones_de_uso_Tab, 'push');
            app.Inicio3_Button.ButtonPushedFcn = createCallbackFcn(app, @Inicio3_ButtonPushed, true);
            app.Inicio3_Button.Text = 'Inicio';
            app.CE3_Image = uiimage(app.Instrucciones_de_uso_Tab);
            app.CE3_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Instrucciones_de_uso_Label = uilabel(app.Instrucciones_de_uso_Tab);
            app.Instrucciones_de_uso_Label.Text = 'Instrucciones de uso';
            app.Atras3_Button = uibutton(app.Instrucciones_de_uso_Tab, 'push');
            app.Atras3_Button.ButtonPushedFcn = createCallbackFcn(app, @Atras3_ButtonPushed, true);
            app.Atras3_Button.Text = 'Atrás';
            app.Etiqueta_Tab = uitab(app.TabGroup);
            app.Etiqueta_Tab.Title = 'Etiqueta';
            app.Marco3_Image_3 = uiimage(app.Etiqueta_Tab);
            app.Marco3_Image_3.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU3_Image_3 = uiimage(app.Etiqueta_Tab);
            app.MU3_Image_3.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard3_Image2_2 = uiimage(app.Etiqueta_Tab);
            app.VisioGuard3_Image2_2.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan3_Label_2 = uilabel(app.Etiqueta_Tab);
            app.GlaucoScan3_Label_2.Text = 'GlaucoScan';
            app.Inicio3_Button_2 = uibutton(app.Etiqueta_Tab, 'push');
            app.Inicio3_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Inicio3_Button_2Pushed, true);
            app.Inicio3_Button_2.Text = 'Inicio';
            app.CE3_Image_2 = uiimage(app.Etiqueta_Tab);
            app.CE3_Image_2.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Etiqueta_Label = uilabel(app.Etiqueta_Tab);
            app.Etiqueta_Label.Text = 'Etiqueta';
            app.Atras4_Button_3 = uibutton(app.Etiqueta_Tab, 'push');
            app.Atras4_Button_3.ButtonPushedFcn = createCallbackFcn(app, @Atras4_Button_3Pushed, true);
            app.Atras4_Button_3.Text = 'Atrás';
            app.Sobre_nosotros_Tab = uitab(app.TabGroup);
            app.Sobre_nosotros_Tab.Title = 'Sobre_nosotros';
            app.Marco4_Image = uiimage(app.Sobre_nosotros_Tab);
            app.Marco4_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU4_Image = uiimage(app.Sobre_nosotros_Tab);
            app.MU4_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard4_Image = uiimage(app.Sobre_nosotros_Tab);
            app.VisioGuard4_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan4_Label = uilabel(app.Sobre_nosotros_Tab);
            app.GlaucoScan4_Label.Text = 'GlaucoScan';
            app.Inicio4_Button = uibutton(app.Sobre_nosotros_Tab, 'push');
            app.Inicio4_Button.ButtonPushedFcn = createCallbackFcn(app, @Inicio4_ButtonPushed, true);
            app.Inicio4_Button.Text = 'Inicio';
            app.CE4_Image = uiimage(app.Sobre_nosotros_Tab);
            app.CE4_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Sobre_nosotros_Label = uilabel(app.Sobre_nosotros_Tab);
            app.Sobre_nosotros_Label.Text = 'Sobre nosotros';
            app.Texto_sobre_nosotros_TextArea = uitextarea(app.Sobre_nosotros_Tab);
            app.Texto_sobre_nosotros_TextArea.Editable = 'off';
            app.Texto_sobre_nosotros_TextArea.Value = {'¡Bienvenido a VisioGuard!'; ''; 'VisioGuard es una empresa emergente con sede en Arrasate (Gipuzkoa, Euskal Herria), fundada en el año 2024 y compuesta por un equipo apasionado de siete individuos. De esos siete, cinco son fundadores y provienen de un trasfondo en ingeniería biomédica de Mondragon Unibertitatea.'; ''; 'Nos enorgullece ofrecer soluciones innovadoras en el campo de la salud, específicamente centradas en la detección temprana y precisa del glaucoma. Creemos firmemente en el poder de la tecnología para mejorar la calidad de vida de las personas, y es por eso que hemos dirigido nuestros esfuerzos hacia el desarrollo de software de vanguardia que pueda ayudar a diagnosticar esta enfermedad ocular de manera rápida y efectiva.'; ''; 'En VisioGuard, estamos comprometidos con la excelencia en cada paso del camino. Desde la concepción de nuestras ideas hasta la implementación y la entrega final, nos esforzamos por mantener los más altos estándares de calidad y precisión. Nuestro equipo multidisciplinario combina habilidades técnicas con un profundo entendimiento de las necesidades clínicas, lo que nos permite crear soluciones que realmente marcan la diferencia en la atención médica.'; ''; 'En VisioGuard, nuestro objetivo es claro: mejorar la salud visual y transformar vidas a través de la innovación tecnológica.'; ''; '¡Gracias por elegir VisioGuard!'};
            app.FotoEquipo_Image = uiimage(app.Sobre_nosotros_Tab);
            app.Atras4_Button = uibutton(app.Sobre_nosotros_Tab, 'push');
            app.Atras4_Button.ButtonPushedFcn = createCallbackFcn(app, @Atras4_ButtonPushed, true);
            app.Atras4_Button.Text = 'Atrás';
            app.Slogan_Label = uilabel(app.Sobre_nosotros_Tab);
            app.Slogan_Label.Text = 'Ojos que no ven, GlaucoScan que lo siente';
            app.Sobre_nosotros_Label_2 = uilabel(app.Sobre_nosotros_Tab);
            app.Sobre_nosotros_Label_2.Text = 'Equipo';
            % Show the figure after all components are created
            app.ventana1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisioGuard_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ventana1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ventana1)
        end
    end
end